classdef Amplitude_Modulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PerformModulationandDemodulationButton  matlab.ui.control.Button
        LowPassFilterOrderEditField    matlab.ui.control.NumericEditField
        LowPassFilterOrderEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField              matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel         matlab.ui.control.Label
        StopTimeEditField              matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel         matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        MessageSignalFrequencyEditField  matlab.ui.control.NumericEditField
        MessageSignalFrequencyEditFieldLabel  matlab.ui.control.Label
        MessageSignalAmplitudeEditField  matlab.ui.control.NumericEditField
        MessageSignalAmplitudeEditFieldLabel  matlab.ui.control.Label
        CarrierSignalFrequencyEditField  matlab.ui.control.NumericEditField
        CarrierSignalFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierSignalAmplitudeEditField  matlab.ui.control.NumericEditField
        CarrierSignalAmplitudeEditFieldLabel  matlab.ui.control.Label
        ModulationSchemeDropDown       matlab.ui.control.DropDown
        ModulationSchemeDropDownLabel  matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModulationSchemeDropDown
        function ModulationSchemeDropDownValueChanged(app, event)
            choice = app.ModulationSchemeDropDown.Value;
            
        end

        % Value changed function: CarrierSignalAmplitudeEditField
        function CarrierSignalAmplitudeEditFieldValueChanged(app, event)
            Ac= app.CarrierSignalAmplitudeEditField.Value;
            
        end

        % Value changed function: MessageSignalAmplitudeEditField
        function MessageSignalAmplitudeEditFieldValueChanged(app, event)
            Am = app.MessageSignalAmplitudeEditField.Value;
            
        end

        % Value changed function: CarrierSignalFrequencyEditField
        function CarrierSignalFrequencyEditFieldValueChanged(app, event)
            fc = app.CarrierSignalFrequencyEditField.Value;
            
        end

        % Value changed function: MessageSignalFrequencyEditField
        function MessageSignalFrequencyEditFieldValueChanged(app, event)
            fm = app.MessageSignalFrequencyEditField.Value;
            
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            start_time = app.StartTimeEditField.Value;
            
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            stop_time = app.StopTimeEditField.Value;
            
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            step_size = app.StepSizeEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

             t=app.start_time:app.step_size:app.stop_time;
             c=Ac*cos(2*pi*fc*t);
             plot(app.UIAxes,t,c)
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
             t=start_time:step_size:stop_time;
             m=Am*cos(2*pi*fm*t);
             plot(app.UIAxes,t,m)
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            if choice=="DSBAM"
               miu=AM/Ac;
               mod=Ac*(1+miu*cos(2*pi*fm*t)).*cos(2*pi*fc*t);
            elseif choice=="DSBSC"
               mod=m.*c;
            elseif choice=="SSBSC(LOWER)"
               mod= (Ac/2)*cos(2*pi*fc*t).*m + (Ac/2)*sin(2*pi*fc*t).*(imag(hilbert(m)));
            else
               mod= (Ac/2)*cos(2*pi*fc*t).*m - (Ac/2)*sin(2*pi*fc*t).*(imag(hilbert(m)));
            end
             plot(app.UIAxes,t,mod) 
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
                 cut=(fm+10)/(fs/2);
                 [b,a] = butter(o,cut);
                 demod=(mod).*(c);
                 demod_filtered=filter(b,a,demod)-Ac;
                 plot(app.UIAxes,t,demod_filtered)
        end

        % Value changed function: LowPassFilterOrderEditField
        function LowPassFilterOrderEditFieldValueChanged(app, event)
            o= app.LowPassFilterOrderEditField.Value; 
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
             n=length(mod);
             fs=1/step_size;
             f=-fs/2:fs/n:(fs/2-fs/n);
             mod_freqdomain=fftshift(abs(fft(mod)));
             plot(app.UIAxes,f,mod_freqdomain)
        end

        % Button pushed function: PerformModulationandDemodulationButton
        function PerformModulationandDemodulationButtonPushed(app, event)
            choice = app.ModulationSchemeDropDown.Value; 
            Ac = app.CarrierSignalAmplitudeEditField.Value;
            fc = app.CarrierSignalFrequencyEditField.Value;
            Am = app.MessageSignalAmplitudeEditField.Value;
            fm = app.MessageSignalFrequencyEditField.Value;
            start_time = app.StartTimeEditField.Value;
            stop_time = app.StopTimeEditField.Value;
            step_size = app.StepSizeEditField.Value;
            o= app.LowPassFilterOrderEditField.Value; 
            t=start_time:step_size:stop_time;
            c=Ac*cos(2*pi*fc*t);
            m=Am*cos(2*pi*fm*t);
            if choice=="DSBAM"
               miu=Am/Ac;
               mod=Ac*(1+miu*cos(2*pi*fm*t)).*cos(2*pi*fc*t);
            elseif choice=="DSBSC"
               mod=m.*c;
            elseif choice=="SSBSC(LOWER)"
               mod= (Ac/2)*cos(2*pi*fc*t).*m + (Ac/2)*sin(2*pi*fc*t).*(imag(hilbert(m)));
            else
               mod= (Ac/2)*cos(2*pi*fc*t).*m - (Ac/2)*sin(2*pi*fc*t).*(imag(hilbert(m)));
            end
            n=length(mod);
            fs=1/step_size;
            f=-fs/2:fs/n:(fs/2-fs/n);
            mod_freqdomain=fftshift(abs(fft(mod)));
            cut=(fc)/(fs/2);
            [b,a] = butter(o,cut);
            demod=mod.*c;
            demod_filtered=filter(b,a,demod);
            plot(app.UIAxes,t,c)
            plot(app.UIAxes2,t,m)
            plot(app.UIAxes3,t,mod)
            plot(app.UIAxes4,t,demod_filtered)
            plot(app.UIAxes5,f,mod_freqdomain)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carrier Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Modulated Signal')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Demodulated Signal')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Modulated Signal Frequency Domain')
            xlabel(app.UIAxes5, 'Frequency')
            ylabel(app.UIAxes5, 'Amplitude')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.ModulationSchemeDropDownLabel = uilabel(app.UIFigure);
            app.ModulationSchemeDropDownLabel.Text = 'Modulation Scheme';
            app.ModulationSchemeDropDown = uidropdown(app.UIFigure);
            app.ModulationSchemeDropDown.Items = {'DSBAM', 'DSBSC', 'SSBSC(UPPER) ', 'SSBSC(LOWER)'};
            app.ModulationSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationSchemeDropDownValueChanged, true);
            app.ModulationSchemeDropDown.Value = 'DSBAM';
            app.CarrierSignalAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierSignalAmplitudeEditFieldLabel.Text = 'Carrier Signal Amplitude';
            app.CarrierSignalAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierSignalAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierSignalAmplitudeEditFieldValueChanged, true);
            app.CarrierSignalFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierSignalFrequencyEditFieldLabel.Text = 'Carrier Signal Frequency';
            app.CarrierSignalFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierSignalFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierSignalFrequencyEditFieldValueChanged, true);
            app.MessageSignalAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MessageSignalAmplitudeEditFieldLabel.Text = 'Message Signal Amplitude';
            app.MessageSignalAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MessageSignalAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MessageSignalAmplitudeEditFieldValueChanged, true);
            app.MessageSignalFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.MessageSignalFrequencyEditFieldLabel.Text = 'Message Signal Frequency';
            app.MessageSignalFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MessageSignalFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MessageSignalFrequencyEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.StopTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.LowPassFilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.LowPassFilterOrderEditFieldLabel.Text = 'Low Pass Filter Order';
            app.LowPassFilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPassFilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterOrderEditFieldValueChanged, true);
            app.PerformModulationandDemodulationButton = uibutton(app.UIFigure, 'push');
            app.PerformModulationandDemodulationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformModulationandDemodulationButtonPushed, true);
            app.PerformModulationandDemodulationButton.Text = 'Perform Modulation and Demodulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Amplitude_Modulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end